classdef BeamDeflection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputPanel                      matlab.ui.container.Panel
        TypeofBeamDropDownLabel         matlab.ui.control.Label
        TypeofBeamDropDown              matlab.ui.control.DropDown
        MomentMoPanel                   matlab.ui.container.Panel
        MoinKNmLabel                    matlab.ui.control.Label
        MoinKNmEditField                matlab.ui.control.NumericEditField
        binmEditFieldLabel              matlab.ui.control.Label
        binmEditField                   matlab.ui.control.NumericEditField
        UniformlyDistributedLoadwPanel  matlab.ui.container.Panel
        winKNmLabel                     matlab.ui.control.Label
        winKNmEditField                 matlab.ui.control.NumericEditField
        a2inmEditFieldLabel             matlab.ui.control.Label
        a2inmEditField                  matlab.ui.control.NumericEditField
        a1inmEditFieldLabel             matlab.ui.control.Label
        a1inmEditField                  matlab.ui.control.NumericEditField
        AnalyseButton                   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        MaterialPropertiesPanel         matlab.ui.container.Panel
        FlexturalRigidityinNmLabel      matlab.ui.control.Label
        FlexturalRigidityinNmEditField  matlab.ui.control.NumericEditField
        LengthLinmEditFieldLabel        matlab.ui.control.Label
        LengthLinmEditField             matlab.ui.control.NumericEditField
        ConcentratedLoadPPanel_2        matlab.ui.container.Panel
        ainmEditField_2Label            matlab.ui.control.Label
        ainmEditField_2                 matlab.ui.control.NumericEditField
        PinKNEditField_2Label           matlab.ui.control.Label
        PinKNEditField_2                matlab.ui.control.NumericEditField
        ValuesatMaximumDeflectionPanel  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Max_xDistanceinmLabel           matlab.ui.control.Label
        DelectioninmmLabel              matlab.ui.control.Label
        ShearForceinKNLabel             matlab.ui.control.Label
        BendingMomentKNmLabel           matlab.ui.control.Label
        BendingMomentLabel              matlab.ui.control.Label
        ANDLabel                        matlab.ui.control.Label
        ShearForceDiagramLabel          matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        GraphsPanel                     matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        TablePanel                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        SearchPanel                     matlab.ui.container.Panel
        x_ValueDistanceinmEditFieldLabel  matlab.ui.control.Label
        x_ValueDistanceinmEditField     matlab.ui.control.NumericEditField
        SearchButton                    matlab.ui.control.StateButton
        DeflectioninmmLabel             matlab.ui.control.Label
        ShearForceinKNLabel_2           matlab.ui.control.Label
        BendingMomentKNmLabel_2         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
        end

        % Callback function: AnalyseButton, UITable
        function AnalyseButtonPushed(app, event)
           
            %input from input panel
            flex_rig = app.FlexturalRigidityinNmEditField.Value;
            L = app.LengthLinmEditField.Value;
            P = 1000 * (app.PinKNEditField_2.Value);
            a = app.ainmEditField_2.Value;
            Mo = 1000 * (app.MoinKNmEditField.Value);
            b = app.binmEditField.Value;
            w = 1000 * (app.winKNmEditField.Value);
            a1 = app.a1inmEditField.Value;
            a2 = app.a2inmEditField.Value;
            value = app.TypeofBeamDropDown.Value;  
            
            x = 0:0.01:L;
            R1 = (Mo/L)- P*(1-(a/L))- w*(a2-a1)*(1-((a1+a2)/(2*L)));
            
            %shear force
            x_point_load = [0*ones(size(0:0.01:a-0.01)) ones(size(a:0.01:L))];
            V_point_load = (P/1000) * x_point_load;          
            
            x_uld_load = [0*ones(size(0:0.01:a1-0.01)) 0:0.01:a2-a1-0.01 (a2-a1)*ones(size(a2:0.01:L))];
            V_udl_load = (w/1000) * x_uld_load;
            
            V_support_load = (R1/1000);
            
            %bending moment
            x_pure_bending_moment = [0*ones(size(0:0.01:b-0.01)) ones(size(b:0.01:L))];
            M_pure_bending_moment = -(Mo/1000) * x_pure_bending_moment;
            
            x_point_load_bending = [0*ones(size(0:0.01:a-0.01)) [a:0.01:L]-a];
            M_point_load_bending = (P/1000) * x_point_load_bending;
            
            x_udl_bending = [0*ones(size(0:0.01:a1-0.01)) [0:0.01:a2-a1-0.01].^2 [a2-a1:0.01:L-a1].^2-[0:0.01:L-a2].^2];
            M_udl_load_bending = (w/2000) * x_udl_bending;
            
            M_supported_load = (R1/1000) * x;
            
            
            %Type of Beam
            if (value(1) == 'C')
               V_final = V_point_load + V_udl_load ;
               M_final = M_point_load_bending + M_pure_bending_moment + M_udl_load_bending ;
               
               c1 = Mo*(L-b) - (P/2)*((L-a)^2) - (w/6)*((L-a1)^3-(L-a2)^3);
               c2 = (Mo/2)*((L-b)^2) - (P/6)*((L-a)^3) - (w/24)*((L-a1)^4-(L-a2)^4)-c1*L;
               
               deflection = 1000 * (((-Mo/2)*([0*ones(size(0:0.01:b-0.01)) [0:0.01:L-b].^2]) + (P/6) * ([0*ones(size(0:0.01:a-0.01)) [0:0.01:L-a].^3]) + (w/24) * ([0*ones(size(0:0.01:a1-0.01)) [0:0.01:a2-a1-0.01].^4 [a2-a1:0.01:L-a1].^4-[0:0.01:L-a2].^4]) + c1 * x + c2)/(flex_rig));
               
               if (-min(deflection) > max(deflection))
                   deflection_max = min(deflection);
               else
                   deflection_max = max(deflection);
               end
               index_max = find(deflection == deflection_max);
               x_max = x(index_max);
               def_max = deflection(index_max);
               V_max = V_final(index_max);
               M_max = M_final(index_max);
               app.Label.Text = sprintf('%.4f', x_max(1));
               app.Label_2.Text = sprintf('%.4ld', def_max(1));
               app.Label_3.Text = sprintf('%.4ld', V_max(1));
               app.Label_4.Text = sprintf('%.4ld', M_max(1));
               
               app.UIAxes_3.XAxisLocation = 'origin';
               app.UIAxes_4.XAxisLocation = 'origin';
               app.UIAxes_5.XAxisLocation = 'origin';
               
               app.UIAxes_3.YAxisLocation = 'origin';
               app.UIAxes_4.YAxisLocation = 'origin';
               app.UIAxes_5.YAxisLocation = 'origin';
               
               plot(app.UIAxes_3,x,V_final);
               plot(app.UIAxes_4,x,M_final);
               plot(app.UIAxes_5,x,deflection);
               
               area(app.UIAxes_3,x,V_final,'FaceColor',[0.5647 0.9333 0.5647]);
               area(app.UIAxes_4,x,M_final,'FaceColor',[1 0.6470 0]);
               area(app.UIAxes_5,x,deflection,'FaceColor',[0.5647 0.4039 0.6549]);
              
               i_max = size(x);
               data = [];
               for i = 1:1:i_max(2)
                    data = [data; x(i) deflection(i) V_final(i) M_final(i)];
               end
               app.UITable.Data = data;
               
               setappdata(app.SearchButton,'x',x);
               setappdata(app.SearchButton,'deflection',deflection);
               setappdata(app.SearchButton,'V_final',V_final);
               setappdata(app.SearchButton,'M_final',M_final);
               
            elseif (value(1) == 'S')
               V_final = V_point_load + V_udl_load + V_support_load ;
               M_final = M_point_load_bending + M_pure_bending_moment + M_udl_load_bending + M_supported_load ;
                
               c1 = ((Mo/2)*((L-b)^2) - (P/6)*((L-a)^3) - (w/24)*((L-a1)^4-(L-a2)^4)-R1*((L^3)/6))/L;
               
               deflection = 1000 * (((R1*((x.^3)/6)) + (-Mo/2)*([0*ones(size(0:0.01:b-0.01)) [0:0.01:L-b].^2]) + (P/6) * ([0*ones(size(0:0.01:a-0.01)) [0:0.01:L-a].^3]) + (w/24) * ([0*ones(size(0:0.01:a1-0.01)) [0:0.01:a2-a1-0.01].^4 [a2-a1:0.01:L-a1].^4-[0:0.01:L-a2].^4]) + c1 * x)/(flex_rig));
               
               if (-min(deflection) > max(deflection))
                   deflection_max = min(deflection);
               else
                   deflection_max = max(deflection);
               end
               index_max = find(deflection == deflection_max);
               x_max = x(index_max);
               def_max = deflection(index_max);
               V_max = V_final(index_max);
               M_max = M_final(index_max);
               app.Label.Text = sprintf('%.4f', x_max(1));
               app.Label_2.Text = sprintf('%.4ld', def_max(1));
               app.Label_3.Text = sprintf('%.4ld', V_max(1));
               app.Label_4.Text = sprintf('%.4ld', M_max(1));
               
               app.UIAxes_3.XAxisLocation = 'origin';
               app.UIAxes_4.XAxisLocation = 'origin';
               app.UIAxes_5.XAxisLocation = 'origin';
               
               app.UIAxes_3.YAxisLocation = 'origin';
               app.UIAxes_4.YAxisLocation = 'origin';
               app.UIAxes_5.YAxisLocation = 'origin';
               
               plot(app.UIAxes_3,x,V_final);
               plot(app.UIAxes_4,x,M_final);
               plot(app.UIAxes_5,x,deflection);
               
               area(app.UIAxes_3,x,V_final,'FaceColor',[0.5647 0.9333 0.5647]);
               area(app.UIAxes_4,x,M_final,'FaceColor',[1 0.6470 0]);
               area(app.UIAxes_5,x,deflection,'FaceColor',[0.5647 0.4039 0.6549]);
               
               
               i_max = size(x);
               data = [];
               for i = 1:1:i_max(2)
                    data = [data; x(i) deflection(i) V_final(i) M_final(i)];
               end
               app.UITable.Data = data;
               
               
               setappdata(app.SearchButton,'x',x);
               setappdata(app.SearchButton,'deflection',deflection);
               setappdata(app.SearchButton,'V_final',V_final);
               setappdata(app.SearchButton,'M_final',M_final);
               
            end
        end

        % Value changed function: TypeofBeamDropDown
        function TypeofBeamDropDownValueChanged(app, event)
            value = app.TypeofBeamDropDown.Value;
            
        end

        % Value changed function: SearchButton
        function SearchButtonValueChanged(app, event)
            value = app.SearchButton.Value;
            x_Value = app.x_ValueDistanceinmEditField.Value;
            
             x = getappdata(app.SearchButton,'x');
             deflection = getappdata(app.SearchButton,'deflection');
             V_final = getappdata(app.SearchButton,'V_final');
             M_final = getappdata(app.SearchButton,'M_final');
             
            index_x_Value = find(x == x_Value);
            def_x_Value = deflection(index_x_Value);
            V_x_Value = V_final(index_x_Value);
            M_x_Value = M_final(index_x_Value);
               
            app.Label_5.Text = sprintf('%.4ld', def_x_Value(1));
            app.Label_6.Text = sprintf('%.4ld', V_x_Value(1));
            app.Label_7.Text = sprintf('%.4ld', M_x_Value(1));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.TypeofBeamDropDownLabel = uilabel(app.InputPanel);
            app.TypeofBeamDropDownLabel.Text = {'Type of Beam'; ''};
            app.TypeofBeamDropDown = uidropdown(app.InputPanel);
            app.TypeofBeamDropDown.Items = {'Cantilever', 'Simply Supported '};
            app.TypeofBeamDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofBeamDropDownValueChanged, true);
            app.TypeofBeamDropDown.Value = 'Simply Supported ';
            app.MomentMoPanel = uipanel(app.InputPanel);
            app.MomentMoPanel.Title = 'Moment (Mo)';
            app.MoinKNmLabel = uilabel(app.MomentMoPanel);
            app.MoinKNmLabel.Text = 'Mo (in KNm)';
            app.MoinKNmEditField = uieditfield(app.MomentMoPanel, 'numeric');
            app.binmEditFieldLabel = uilabel(app.MomentMoPanel);
            app.binmEditFieldLabel.Text = 'b ( in ''m'')';
            app.binmEditField = uieditfield(app.MomentMoPanel, 'numeric');
            app.UniformlyDistributedLoadwPanel = uipanel(app.InputPanel);
            app.UniformlyDistributedLoadwPanel.Title = 'Uniformly Distributed Load (w)';
            app.winKNmLabel = uilabel(app.UniformlyDistributedLoadwPanel);
            app.winKNmLabel.Text = 'w (in ''KN/m'')';
            app.winKNmEditField = uieditfield(app.UniformlyDistributedLoadwPanel, 'numeric');
            app.a2inmEditFieldLabel = uilabel(app.UniformlyDistributedLoadwPanel);
            app.a2inmEditFieldLabel.Text = 'a2 ( in ''m'')';
            app.a2inmEditField = uieditfield(app.UniformlyDistributedLoadwPanel, 'numeric');
            app.a1inmEditFieldLabel = uilabel(app.UniformlyDistributedLoadwPanel);
            app.a1inmEditFieldLabel.Text = 'a1 ( in ''m'')';
            app.a1inmEditField = uieditfield(app.UniformlyDistributedLoadwPanel, 'numeric');
            app.AnalyseButton = uibutton(app.InputPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.ResetButton = uibutton(app.InputPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.MaterialPropertiesPanel = uipanel(app.InputPanel);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.FlexturalRigidityinNmLabel = uilabel(app.MaterialPropertiesPanel);
            app.FlexturalRigidityinNmLabel.Text = {'Flextural Rigidity'; '(in N. m²)      '};
            app.FlexturalRigidityinNmEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.LengthLinmEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.LengthLinmEditFieldLabel.Text = 'Length (L in ''m'')';
            app.LengthLinmEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.ConcentratedLoadPPanel_2 = uipanel(app.InputPanel);
            app.ConcentratedLoadPPanel_2.Title = 'Concentrated Load (P)';
            app.ainmEditField_2Label = uilabel(app.ConcentratedLoadPPanel_2);
            app.ainmEditField_2Label.Text = 'a ( in ''m'')';
            app.ainmEditField_2 = uieditfield(app.ConcentratedLoadPPanel_2, 'numeric');
            app.PinKNEditField_2Label = uilabel(app.ConcentratedLoadPPanel_2);
            app.PinKNEditField_2Label.Text = 'P (in KN)';
            app.PinKNEditField_2 = uieditfield(app.ConcentratedLoadPPanel_2, 'numeric');
            app.ValuesatMaximumDeflectionPanel = uipanel(app.InputPanel);
            app.ValuesatMaximumDeflectionPanel.Title = 'Values at Maximum Deflection';
            app.Label = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.Label_4.Text = '';
            app.Max_xDistanceinmLabel = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.Max_xDistanceinmLabel.Text = 'Max_x (Distance in ''m''):';
            app.DelectioninmmLabel = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.DelectioninmmLabel.Text = 'Delection (in ''mm'') :';
            app.ShearForceinKNLabel = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.ShearForceinKNLabel.Text = 'Shear Force (in ''KN'') :';
            app.BendingMomentKNmLabel = uilabel(app.ValuesatMaximumDeflectionPanel);
            app.BendingMomentKNmLabel.Text = 'Bending Moment (KN-m):';
            app.BendingMomentLabel = uilabel(app.InputPanel);
            app.BendingMomentLabel.Text = 'Bending Moment ';
            app.ANDLabel = uilabel(app.InputPanel);
            app.ANDLabel.Text = 'AND';
            app.ShearForceDiagramLabel = uilabel(app.InputPanel);
            app.ShearForceDiagramLabel.Text = 'Shear Force Diagram';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output Panel';
            app.GraphsPanel = uipanel(app.OutputPanel);
            app.GraphsPanel.Title = 'Graphs';
            app.UIAxes_3 = uiaxes(app.GraphsPanel);
            title(app.UIAxes_3, 'Shear Force')
            xlabel(app.UIAxes_3, 'x(Distance in ''m'')')
            ylabel(app.UIAxes_3, 'V  (KN)')
            app.UIAxes_4 = uiaxes(app.GraphsPanel);
            title(app.UIAxes_4, 'Bending Moment')
            xlabel(app.UIAxes_4, 'x(Distance in ''m'')')
            ylabel(app.UIAxes_4, ' M  (KN-m)')
            app.UIAxes_5 = uiaxes(app.GraphsPanel);
            title(app.UIAxes_5, 'Deflection')
            xlabel(app.UIAxes_5, 'x(Distance in ''m'')')
            ylabel(app.UIAxes_5, 'Deflection  (mm)')
            app.TablePanel = uipanel(app.OutputPanel);
            app.TablePanel.Title = 'Table';
            app.UITable = uitable(app.TablePanel);
            app.UITable.ColumnName = {'x(Distance in ''m'')'; 'Deflection (in ''mm'')'; 'Shear Force (in ''kN'')'; 'Bending Moment (''KN-m'')'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.SearchPanel = uipanel(app.OutputPanel);
            app.SearchPanel.Title = 'Search Panel';
            app.x_ValueDistanceinmEditFieldLabel = uilabel(app.SearchPanel);
            app.x_ValueDistanceinmEditFieldLabel.Text = {'x_Value        '; '(Distance in ''m''):'};
            app.x_ValueDistanceinmEditField = uieditfield(app.SearchPanel, 'numeric');
            app.SearchButton = uibutton(app.SearchPanel, 'state');
            app.SearchButton.ValueChangedFcn = createCallbackFcn(app, @SearchButtonValueChanged, true);
            app.SearchButton.Text = 'Search';
            app.DeflectioninmmLabel = uilabel(app.OutputPanel);
            app.DeflectioninmmLabel.Text = 'Deflection (in ''mm'') :';
            app.ShearForceinKNLabel_2 = uilabel(app.OutputPanel);
            app.ShearForceinKNLabel_2.Text = 'Shear Force (in ''KN'') :';
            app.BendingMomentKNmLabel_2 = uilabel(app.OutputPanel);
            app.BendingMomentKNmLabel_2.Text = 'Bending Moment(KN-m):';
            app.Label_5 = uilabel(app.OutputPanel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.OutputPanel);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.OutputPanel);
            app.Label_7.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamDeflection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end